classdef COVID19_Matlab_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19UIFigure                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StatisticsTab                   matlab.ui.container.Tab
        place                           matlab.ui.container.ButtonGroup
        all                             matlab.ui.control.ToggleButton
        specific                        matlab.ui.control.ToggleButton
        TabGroup_2                      matlab.ui.container.TabGroup
        InfectionsTab                   matlab.ui.container.Tab
        ax_infections                   matlab.ui.control.UIAxes
        inf_type                        matlab.ui.container.ButtonGroup
        act                             matlab.ui.control.ToggleButton
        rec                             matlab.ui.control.ToggleButton
        cum                             matlab.ui.control.ToggleButton
        pt                              matlab.ui.control.ToggleButton
        rpt                             matlab.ui.control.ToggleButton
        inf_p_test                      matlab.ui.control.ToggleButton
        days_rec_inf                    matlab.ui.control.NumericEditField
        daywindowLabel                  matlab.ui.control.Label
        perdayoverLabel                 matlab.ui.control.Label
        DeathsTab                       matlab.ui.container.Tab
        ax_deaths                       matlab.ui.control.UIAxes
        GrowthRatebyRegionTab           matlab.ui.container.Tab
        UpdateButton                    matlab.ui.control.Button
        gr_table                        matlab.ui.control.Table
        gr_hist                         matlab.ui.control.UIAxes
        ByCountryorRegionButtonGroup    matlab.ui.container.ButtonGroup
        CountryButton                   matlab.ui.control.ToggleButton
        RegionButton                    matlab.ui.control.ToggleButton
        PlotTypesButtonGroup            matlab.ui.container.ButtonGroup
        lin                             matlab.ui.control.ToggleButton
        log                             matlab.ui.control.ToggleButton
        add_trend_lines                 matlab.ui.control.CheckBox
        daystocomputetrendLabel         matlab.ui.control.Label
        CountriesLabel                  matlab.ui.control.Label
        countries                       matlab.ui.control.ListBox
        UseLabel                        matlab.ui.control.Label
        days_for_trend                  matlab.ui.control.NumericEditField
        mostrecentdaystovalidatepredictionsLabel  matlab.ui.control.Label
        HoldoutLabel                    matlab.ui.control.Label
        days_for_pred                   matlab.ui.control.NumericEditField
        StateProvinceLabel              matlab.ui.control.Label
        states                          matlab.ui.control.ListBox
        nmrl                            matlab.ui.container.ButtonGroup
        abs                             matlab.ui.control.ToggleButton
        rel                             matlab.ui.control.ToggleButton
        nmrl_2                          matlab.ui.container.ButtonGroup
        abs_date                        matlab.ui.control.ToggleButton
        rel_date                        matlab.ui.control.ToggleButton
        PeopleDropDown                  matlab.ui.control.DropDown
        PeopleLabel                     matlab.ui.control.Label
        UpdatePlotsButton               matlab.ui.control.Button
        TrendTypeButtonGroup            matlab.ui.container.ButtonGroup
        LogLinearButton                 matlab.ui.control.ToggleButton
        LogQuadraticButton              matlab.ui.control.ToggleButton
        MapsTab                         matlab.ui.container.Tab
        map                             matlab.ui.control.UIAxes
        ShowMapButton                   matlab.ui.control.Button
        TimeSliderLabel                 matlab.ui.control.Label
        TimeSlider                      matlab.ui.control.Slider
        GenerateMovieButton             matlab.ui.control.Button
        RegionDropDownLabel             matlab.ui.control.Label
        RegionDropDown                  matlab.ui.control.DropDown
        StatustoshowLabel               matlab.ui.control.Label
        map_what                        matlab.ui.control.DropDown
        nmrl_3                          matlab.ui.container.ButtonGroup
        abs_2                           matlab.ui.control.ToggleButton
        rel_2                           matlab.ui.control.ToggleButton
        SIRModelFitTab                  matlab.ui.container.Tab
        SubsetofCountriesListBoxLabel_2  matlab.ui.control.Label
        countries_2                     matlab.ui.control.ListBox
        sir_plot                        matlab.ui.control.UIAxes
        PlotTypesButtonGroup_2          matlab.ui.container.ButtonGroup
        lin_2                           matlab.ui.control.ToggleButton
        log_2                           matlab.ui.control.ToggleButton
        update_sir_plot                 matlab.ui.control.Button
        sir_parameters                  matlab.ui.control.Table
        FittoDataButton                 matlab.ui.control.Button
        TrymultipletimesuntilfitsnolongerchangeLabel  matlab.ui.control.Label
        TrymultipleinitialguessestoavoidgettingstuckinlocalminimaLabel  matlab.ui.control.Label
        ExtendedSIRTab                  matlab.ui.container.Tab
        SocialDistancingPreSymptomSliderLabel  matlab.ui.control.Label
        d_pre                           matlab.ui.control.Slider
        AvailableHospitalBedsLabel      matlab.ui.control.Label
        M_Hosp                          matlab.ui.control.Slider
        TimetoVisibleSymptomsdaysSliderLabel  matlab.ui.control.Label
        T_vis                           matlab.ui.control.Slider
        SocialDistancingPostSymptomSliderLabel  matlab.ui.control.Label
        d_post                          matlab.ui.control.Slider
        TimetoRecoverydaysLabel         matlab.ui.control.Label
        t_r                             matlab.ui.control.Slider
        ssa_results                     matlab.ui.control.UIAxes
        InitialSusceptibleEditFieldLabel  matlab.ui.control.Label
        S_0                             matlab.ui.control.NumericEditField
        InitialInfectedUndetectedEditFieldLabel  matlab.ui.control.Label
        IU_0                            matlab.ui.control.NumericEditField
        InitialInfectedDetectedEditFieldLabel  matlab.ui.control.Label
        ID_0                            matlab.ui.control.NumericEditField
        RunSimmulationButton            matlab.ui.control.Button
        MildNoSymptomsLabel             matlab.ui.control.Label
        TimetoRecoverydaysLabel_2       matlab.ui.control.Label
        t_r_nohosp                      matlab.ui.control.Slider
        SevereSymptomsNoHospitalLabel   matlab.ui.control.Label
        TimetoRecoverydaysLabel_3       matlab.ui.control.Label
        t_r_hosp                        matlab.ui.control.Slider
        WithAidofHospitalLabel          matlab.ui.control.Label
        ProbabilityoffatalityLabel      matlab.ui.control.Label
        P_d                             matlab.ui.control.Slider
        MeanNumberofSocialContactsLabel  matlab.ui.control.Label
        MeanNumberofSocialContactsLabel_2  matlab.ui.control.Label
        SevereSymptomsnotreatmentLabel  matlab.ui.control.Label
        per10kpeopleLabel               matlab.ui.control.Label
        TimetobecomeinfectiousdaysLabel  matlab.ui.control.Label
        t_i                             matlab.ui.control.Slider
        TextArea                        matlab.ui.control.TextArea
        SimTypeButtonGroup              matlab.ui.container.ButtonGroup
        ode                             matlab.ui.control.ToggleButton
        ssa                             matlab.ui.control.ToggleButton
        SpatialSIRSimulationTab         matlab.ui.container.Tab
        spat_sim                        matlab.ui.control.UIAxes
        InfectionRatek_iEditFieldLabel  matlab.ui.control.Label
        ki                              matlab.ui.control.NumericEditField
        socialdistancevelocityv_sdEditFieldLabel  matlab.ui.control.Label
        v_sd                            matlab.ui.control.NumericEditField
        cheatersvelocityv_cEditFieldLabel  matlab.ui.control.Label
        v_c                             matlab.ui.control.NumericEditField
        fractionofcheatersetaEditFieldLabel  matlab.ui.control.Label
        eta                             matlab.ui.control.NumericEditField
        populationEditFieldLabel        matlab.ui.control.Label
        pop                             matlab.ui.control.NumericEditField
        InitialnumberinfectedEditFieldLabel  matlab.ui.control.Label
        inf                             matlab.ui.control.NumericEditField
        runsimulationButton             matlab.ui.control.Button
        spat_sim_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        Countries
        States
        DATA % Description
        DATA_Deaths
        DATA_Recov
        DATA_pt
        Lat
        Long
        dates
        Pop_Data
        inf_vs_t
        dth_vs_t
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('../COVID-19/csse_covid_19_data/csse_covid_19_time_series/')
            
            try
                Y = importdata('time_series_covid19_confirmed_global.csv');
            catch
                [~, pathname, ~] = uigetfile('time_series_covid19_confirmed_global.csv','Navigate to find the File time_series_19-covid-Confirmed.csv');
                addpath(pathname);
                Y = importdata('time_series_covid19_confirmed_global.csv');
            end
            
            app.dates = Y.textdata(1,5:end);
            app.DATA = Y.data(:,3:end);
            app.Countries = Y.textdata(2:end,2);
            app.States = Y.textdata(2:end,1);
            app.Lat = Y.data(:,1);
            app.Long = Y.data(:,2);
            
            Y = importdata('time_series_covid19_deaths_global.csv');
            app.DATA_Deaths = Y.data(:,3:end);
            
            Y = importdata('time_series_covid19_recovered_global.csv');
            TMP = Y.data(:,3:end);
            st = Y.textdata(2:end,1);
            co = Y.textdata(2:end,2);
            
            app.DATA_Recov = zeros(size(app.DATA));
            for i=1:length(app.Countries)
                I = strcmp(co,app.Countries{i})&strcmp(st,app.States{i});
                if sum(I)==1
                    app.DATA_Recov(i,:) = TMP(I,:);
                end
            end

            app.countries.Items = unique(app.Countries);
            app.countries.Value = 'US';
            
            %%
            if ~exist('data/state_data.mat','file')
                extract_state_info_from_JHU_data;
            else
                FileInfo1 = dir('data/state_data.mat');
                FileInfo2 = dir('../COVID-19/csse_covid_19_data/csse_covid_19_time_series/time_series_covid19_confirmed_global.csv');
                if FileInfo2.datenum>FileInfo1.datenum
                    extract_state_info_from_JHU_data
                end
            end
            I = ~strcmp(app.Countries,'US');
            load('data/state_data.mat')
            app.DATA = [app.DATA(I,:);cell2mat(Z_DATA(:,5:end))];
            app.DATA_Deaths = [app.DATA_Deaths(I,:);cell2mat(Z_DATA_Deaths(:,5:end))];
            app.DATA_Recov = [app.DATA_Recov(I,:);cell2mat(Z_DATA_Recov(:,5:end))];
            app.DATA_pt(sum(I)+1:sum(I)+size(Z_DATA_Tested,1),:) = cell2mat(Z_DATA_Tested(:,5:end));
            app.Countries = [app.Countries(I);Z_DATA(:,2)];
            app.States = [app.States(I);Z_DATA(:,1)];
            app.Lat = [app.Lat(I);cell2mat(Z_DATA(:,3))];
            app.Long = [app.Long(I);cell2mat(Z_DATA(:,4))];
            
            population_scale(app);
            
            placeSelectionChanged(app)
            
            app.countries_2.Items = unique(app.Countries);
            app.countries_2.Value = 'US';
            
            %% SIR fit tab
            app.sir_parameters.Data = {'k1',1};
            app.sir_parameters.Data(1:6,1) = {'k_i';'k_r';'k_s';'k_d';'t_0';'Suc_0'};
            app.sir_parameters.Data(1:6,2) = {2e-7;2e-3;0.5;1e-2;20;1e6};
            
            %% Maps Tab
            %             app.RegionDropDown.Items = {'World','US','US Per 10k','Europe'};
            app.RegionDropDown.Items = {'World','US','Europe'};
            app.RegionDropDown.Value = {'World'};
            Nt = length(app.dates);
            app.TimeSlider.MajorTicks = sort(unique([[Nt:-7:1]-Nt,7]));
            app.TimeSlider.MinorTicks = [-Nt+1:1:7];
            
            tlb = {app.dates{sort(Nt:-7:1)}};
            tlb{end} = [tlb{end},'(last)'];
            tlb{end+1} = 'future';
            
            app.TimeSlider.MajorTickLabels = tlb;
            app.TimeSlider.Value = 0;
                                
        end

        % Selection changed function: place
        function placeSelectionChanged(app, event)
            if app.specific.Value
                app.countries.Items = unique(app.Countries);
                app.countries.Value = 'US';
                countriesValueChanged(app, event)
            end
            Make_Plot(app)
        end

        % Callback function: PlotTypesButtonGroup, UpdatePlotsButton, 
        % days_for_pred, days_for_trend, days_rec_inf, inf_type, 
        % nmrl, nmrl_2, states
        function PlotTypeButtonGroupSelectionChanged(app, event)
            Make_Plot(app)
        end

        % Value changed function: countries
        function countriesValueChanged(app, event)
            app.specific.Value=1;
            if length(app.countries.Value)==1
                [J] = ismember(app.Countries,app.countries.Value);
                app.states.Items = ['ALL',app.States(J)']';
            else
                app.states.Items = {'ALL'};
            end
            Make_Plot(app)
        end

        % Callback function: ByCountryorRegionButtonGroup, 
        % UpdateButton
        function UpdateButtonPushed(app, event)
            Make_Growth_Rate_Table(app)
        end

        % Callback function: RegionDropDown, ShowMapButton, 
        % TimeSlider, map_what, nmrl_3
        function ShowMapButtonPushed(app, event)
            Make_Map(app);
        end

        % Button pushed function: GenerateMovieButton
        function GenerateMovieButtonPushed(app, event)
            Make_Movie(app)
        end

        % Callback function: M_Hosp, P_d, RunSimmulationButton, 
        % T_vis, d_post, d_pre, t_i, t_r, t_r_hosp, t_r_nohosp
        function RunSimmulationButtonPushed(app, event)
            Run_SSA_Sim(app)
        end

        % Callback function: PlotTypesButtonGroup_2, sir_parameters, 
        % update_sir_plot
        function update_sir_plotPushed(app, event)
            Plot_SIR_Fit(app)
        end

        % Callback function
        function SIRModelFitTabSizeChanged(app, event)
      
        end

        % Button pushed function: FittoDataButton
        function FittoDataButtonPushed(app, event)
            Fit_to_data(app)
        end

        % Value changed function: PeopleDropDown
        function PeopleDropDownValueChanged(app, event)
            app.rel_date.Value=1;
            Make_Plot(app);
        end

        % Button pushed function: runsimulationButton
        function runsimulationButtonPushed(app, event)
            spatial_sir_model(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19UIFigure = uifigure('Visible', 'off');
            app.COVID19UIFigure.Name = 'COVID-19';
            app.TabGroup = uitabgroup(app.COVID19UIFigure);
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.place = uibuttongroup(app.StatisticsTab);
            app.place.SelectionChangedFcn = createCallbackFcn(app, @placeSelectionChanged, true);
            app.place.Title = 'All Countries or Selected Subset of Countries';
            app.all = uitogglebutton(app.place);
            app.all.Text = 'All Countries';
            app.all.Value = true;
            app.specific = uitogglebutton(app.place);
            app.specific.Text = 'Specific Countires';
            app.TabGroup_2 = uitabgroup(app.StatisticsTab);
            app.InfectionsTab = uitab(app.TabGroup_2);
            app.InfectionsTab.Title = 'Infections';
            app.ax_infections = uiaxes(app.InfectionsTab);
            title(app.ax_infections, 'Infections over time')
            xlabel(app.ax_infections, 'Time (days)')
            ylabel(app.ax_infections, 'Infections')
            app.inf_type = uibuttongroup(app.InfectionsTab);
            app.inf_type.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.act = uitogglebutton(app.inf_type);
            app.act.Text = 'Active Infections';
            app.rec = uitogglebutton(app.inf_type);
            app.rec.Text = 'Recent Infections';
            app.cum = uitogglebutton(app.inf_type);
            app.cum.Text = 'Cumulative Infections';
            app.cum.Value = true;
            app.pt = uitogglebutton(app.inf_type);
            app.pt.Text = 'Cumulative Tested';
            app.rpt = uitogglebutton(app.inf_type);
            app.rpt.Text = 'Recent Tested';
            app.inf_p_test = uitogglebutton(app.inf_type);
            app.inf_p_test.Text = 'Fraction Positive';
            app.days_rec_inf = uieditfield(app.InfectionsTab, 'numeric');
            app.days_rec_inf.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_rec_inf.Value = 5;
            app.daywindowLabel = uilabel(app.InfectionsTab);
            app.daywindowLabel.Text = 'day window';
            app.perdayoverLabel = uilabel(app.InfectionsTab);
            app.perdayoverLabel.Text = 'per day over ';
            app.DeathsTab = uitab(app.TabGroup_2);
            app.DeathsTab.Title = 'Deaths';
            app.ax_deaths = uiaxes(app.DeathsTab);
            title(app.ax_deaths, 'Deaths over time')
            xlabel(app.ax_deaths, 'Time (days)')
            ylabel(app.ax_deaths, 'Deaths')
            app.GrowthRatebyRegionTab = uitab(app.TabGroup_2);
            app.GrowthRatebyRegionTab.Title = 'Growth Rate by Region';
            app.UpdateButton = uibutton(app.GrowthRatebyRegionTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.gr_table = uitable(app.GrowthRatebyRegionTab);
            app.gr_table.ColumnName = {'Country/Region'; 'Doubling Time (days)'};
            app.gr_table.RowName = {};
            app.gr_hist = uiaxes(app.GrowthRatebyRegionTab);
            title(app.gr_hist, 'Histogram')
            xlabel(app.gr_hist, 'Doubling Time (days)')
            ylabel(app.gr_hist, 'Probability Density')
            app.ByCountryorRegionButtonGroup = uibuttongroup(app.GrowthRatebyRegionTab);
            app.ByCountryorRegionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.ByCountryorRegionButtonGroup.Title = 'By Country or Region:';
            app.CountryButton = uitogglebutton(app.ByCountryorRegionButtonGroup);
            app.CountryButton.Text = 'Country';
            app.CountryButton.Value = true;
            app.RegionButton = uitogglebutton(app.ByCountryorRegionButtonGroup);
            app.RegionButton.Text = 'Region';
            app.PlotTypesButtonGroup = uibuttongroup(app.StatisticsTab);
            app.PlotTypesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypesButtonGroup.Title = 'Plot Types:';
            app.lin = uitogglebutton(app.PlotTypesButtonGroup);
            app.lin.Text = 'Linear';
            app.log = uitogglebutton(app.PlotTypesButtonGroup);
            app.log.Text = 'Logarithmic';
            app.log.Value = true;
            app.add_trend_lines = uicheckbox(app.StatisticsTab);
            app.add_trend_lines.Text = 'Include Trend lines';
            app.daystocomputetrendLabel = uilabel(app.StatisticsTab);
            app.daystocomputetrendLabel.Text = 'days to compute trend';
            app.CountriesLabel = uilabel(app.StatisticsTab);
            app.CountriesLabel.Text = 'Countries';
            app.countries = uilistbox(app.StatisticsTab);
            app.countries.Multiselect = 'on';
            app.countries.ValueChangedFcn = createCallbackFcn(app, @countriesValueChanged, true);
            app.countries.Value = {'Item 1'};
            app.UseLabel = uilabel(app.StatisticsTab);
            app.UseLabel.Text = 'Use';
            app.days_for_trend = uieditfield(app.StatisticsTab, 'numeric');
            app.days_for_trend.Limits = [3 20];
            app.days_for_trend.RoundFractionalValues = 'on';
            app.days_for_trend.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_for_trend.Value = 5;
            app.mostrecentdaystovalidatepredictionsLabel = uilabel(app.StatisticsTab);
            app.mostrecentdaystovalidatepredictionsLabel.Text = 'most recent days to validate predictions';
            app.HoldoutLabel = uilabel(app.StatisticsTab);
            app.HoldoutLabel.Text = 'Hold out';
            app.days_for_pred = uieditfield(app.StatisticsTab, 'numeric');
            app.days_for_pred.Limits = [0 10];
            app.days_for_pred.RoundFractionalValues = 'on';
            app.days_for_pred.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_for_pred.Value = 1;
            app.StateProvinceLabel = uilabel(app.StatisticsTab);
            app.StateProvinceLabel.Text = 'State/Province';
            app.states = uilistbox(app.StatisticsTab);
            app.states.Items = {'ALL'};
            app.states.Multiselect = 'on';
            app.states.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.states.Value = {'ALL'};
            app.nmrl = uibuttongroup(app.StatisticsTab);
            app.nmrl.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.nmrl.Title = 'Normalization (only for countries with known populations)';
            app.abs = uitogglebutton(app.nmrl);
            app.abs.Text = 'Absolute Numbers';
            app.abs.Value = true;
            app.rel = uitogglebutton(app.nmrl);
            app.rel.Text = 'Per 10k Persons';
            app.nmrl_2 = uibuttongroup(app.StatisticsTab);
            app.nmrl_2.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.nmrl_2.Title = 'Plot time relative to:';
            app.abs_date = uitogglebutton(app.nmrl_2);
            app.abs_date.Text = 'Absolute Date';
            app.abs_date.Value = true;
            app.rel_date = uitogglebutton(app.nmrl_2);
            app.rel_date.Text = 'Time Infection Reached:';
            app.PeopleDropDown = uidropdown(app.StatisticsTab);
            app.PeopleDropDown.ValueChangedFcn = createCallbackFcn(app, @PeopleDropDownValueChanged, true);
            app.PeopleLabel = uilabel(app.StatisticsTab);
            app.PeopleLabel.Text = 'People';
            app.UpdatePlotsButton = uibutton(app.StatisticsTab, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.UpdatePlotsButton.Text = 'Update Plots';
            app.TrendTypeButtonGroup = uibuttongroup(app.StatisticsTab);
            app.TrendTypeButtonGroup.Title = 'Trend Type:';
            app.LogLinearButton = uitogglebutton(app.TrendTypeButtonGroup);
            app.LogLinearButton.Text = 'Log-Linear';
            app.LogLinearButton.Value = true;
            app.LogQuadraticButton = uitogglebutton(app.TrendTypeButtonGroup);
            app.LogQuadraticButton.Text = 'Log-Quadratic';
            app.MapsTab = uitab(app.TabGroup);
            app.MapsTab.Title = 'Maps';
            app.map = uiaxes(app.MapsTab);
            title(app.map, 'Map of Pandemic')
            xlabel(app.map, '')
            ylabel(app.map, '')
            app.map.XTick = [];
            app.map.YTick = [];
            app.ShowMapButton = uibutton(app.MapsTab, 'push');
            app.ShowMapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.ShowMapButton.Text = 'Show Map';
            app.TimeSliderLabel = uilabel(app.MapsTab);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.MapsTab);
            app.TimeSlider.Limits = [-42 7];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.GenerateMovieButton = uibutton(app.MapsTab, 'push');
            app.GenerateMovieButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMovieButtonPushed, true);
            app.GenerateMovieButton.Text = 'Generate Movie';
            app.RegionDropDownLabel = uilabel(app.MapsTab);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.MapsTab);
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.StatustoshowLabel = uilabel(app.MapsTab);
            app.StatustoshowLabel.Text = 'Status to show';
            app.map_what = uidropdown(app.MapsTab);
            app.map_what.Items = {'Deaths', 'Recent Infected', 'Cumulative Infected'};
            app.map_what.ValueChangedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.map_what.Value = 'Deaths';
            app.nmrl_3 = uibuttongroup(app.MapsTab);
            app.nmrl_3.SelectionChangedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.nmrl_3.Title = 'Normalization';
            app.abs_2 = uitogglebutton(app.nmrl_3);
            app.abs_2.Text = 'Absolute Numbers';
            app.abs_2.Value = true;
            app.rel_2 = uitogglebutton(app.nmrl_3);
            app.rel_2.Text = 'Per 10k Persons';
            app.SIRModelFitTab = uitab(app.TabGroup);
            app.SIRModelFitTab.Title = 'SIR Model Fit';
            app.SubsetofCountriesListBoxLabel_2 = uilabel(app.SIRModelFitTab);
            app.SubsetofCountriesListBoxLabel_2.Text = 'Subset of Countries';
            app.countries_2 = uilistbox(app.SIRModelFitTab);
            app.countries_2.Multiselect = 'on';
            app.countries_2.Value = {'Item 1'};
            app.sir_plot = uiaxes(app.SIRModelFitTab);
            title(app.sir_plot, 'Title')
            xlabel(app.sir_plot, 'time (days)')
            ylabel(app.sir_plot, 'number of people (total)')
            app.PlotTypesButtonGroup_2 = uibuttongroup(app.SIRModelFitTab);
            app.PlotTypesButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @update_sir_plotPushed, true);
            app.PlotTypesButtonGroup_2.Title = 'Plot Types:';
            app.lin_2 = uitogglebutton(app.PlotTypesButtonGroup_2);
            app.lin_2.Text = 'Linear';
            app.log_2 = uitogglebutton(app.PlotTypesButtonGroup_2);
            app.log_2.Text = 'Logarithmic';
            app.log_2.Value = true;
            app.update_sir_plot = uibutton(app.SIRModelFitTab, 'push');
            app.update_sir_plot.ButtonPushedFcn = createCallbackFcn(app, @update_sir_plotPushed, true);
            app.update_sir_plot.Text = 'update plot';
            app.sir_parameters = uitable(app.SIRModelFitTab);
            app.sir_parameters.ColumnName = {'Name'; 'Value'};
            app.sir_parameters.RowName = {''; ''};
            app.sir_parameters.ColumnEditable = [false true];
            app.sir_parameters.CellEditCallback = createCallbackFcn(app, @update_sir_plotPushed, true);
            app.FittoDataButton = uibutton(app.SIRModelFitTab, 'push');
            app.FittoDataButton.ButtonPushedFcn = createCallbackFcn(app, @FittoDataButtonPushed, true);
            app.FittoDataButton.Text = 'Fit to Data';
            app.TrymultipletimesuntilfitsnolongerchangeLabel = uilabel(app.SIRModelFitTab);
            app.TrymultipletimesuntilfitsnolongerchangeLabel.Text = 'Try multiple times until fits no longer change.';
            app.TrymultipleinitialguessestoavoidgettingstuckinlocalminimaLabel = uilabel(app.SIRModelFitTab);
            app.TrymultipleinitialguessestoavoidgettingstuckinlocalminimaLabel.Text = 'Try multiple initial guesses to avoid getting stuck in local minima.';
            app.ExtendedSIRTab = uitab(app.TabGroup);
            app.ExtendedSIRTab.Title = 'Extended SIR';
            app.SocialDistancingPreSymptomSliderLabel = uilabel(app.ExtendedSIRTab);
            app.SocialDistancingPreSymptomSliderLabel.Text = 'Social Distancing Pre-Symptom';
            app.d_pre = uislider(app.ExtendedSIRTab);
            app.d_pre.Limits = [0 50];
            app.d_pre.MajorTicks = [0 10 20 30 40 50];
            app.d_pre.MajorTickLabels = {'0', '10', '20', '30', '40', '50'};
            app.d_pre.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.d_pre.Value = 20;
            app.AvailableHospitalBedsLabel = uilabel(app.ExtendedSIRTab);
            app.AvailableHospitalBedsLabel.Text = 'Available Hospital Beds';
            app.M_Hosp = uislider(app.ExtendedSIRTab);
            app.M_Hosp.Limits = [0 1000];
            app.M_Hosp.MajorTicks = [0 200 400 600 800 1000];
            app.M_Hosp.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.M_Hosp.Value = 200;
            app.TimetoVisibleSymptomsdaysSliderLabel = uilabel(app.ExtendedSIRTab);
            app.TimetoVisibleSymptomsdaysSliderLabel.Text = 'Time to Visible Symptoms (days)';
            app.T_vis = uislider(app.ExtendedSIRTab);
            app.T_vis.Limits = [0 50];
            app.T_vis.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.T_vis.Value = 14;
            app.SocialDistancingPostSymptomSliderLabel = uilabel(app.ExtendedSIRTab);
            app.SocialDistancingPostSymptomSliderLabel.Text = 'Social Distancing Post-Symptom';
            app.d_post = uislider(app.ExtendedSIRTab);
            app.d_post.Limits = [0 50];
            app.d_post.MajorTicks = [0 10 20 30 40 50];
            app.d_post.MajorTickLabels = {'0', '10', '20', '30', '40', '50'};
            app.d_post.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.d_post.Value = 2;
            app.TimetoRecoverydaysLabel = uilabel(app.ExtendedSIRTab);
            app.TimetoRecoverydaysLabel.Text = 'Time to Recovery (days)';
            app.t_r = uislider(app.ExtendedSIRTab);
            app.t_r.Limits = [0 50];
            app.t_r.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.t_r.Value = 14;
            app.ssa_results = uiaxes(app.ExtendedSIRTab);
            title(app.ssa_results, 'Title')
            xlabel(app.ssa_results, 'time (days)')
            ylabel(app.ssa_results, 'fraction of population')
            app.InitialSusceptibleEditFieldLabel = uilabel(app.ExtendedSIRTab);
            app.InitialSusceptibleEditFieldLabel.Text = 'Initial Susceptible';
            app.S_0 = uieditfield(app.ExtendedSIRTab, 'numeric');
            app.S_0.Value = 100000;
            app.InitialInfectedUndetectedEditFieldLabel = uilabel(app.ExtendedSIRTab);
            app.InitialInfectedUndetectedEditFieldLabel.Text = 'Initial Infected (Undetected)';
            app.IU_0 = uieditfield(app.ExtendedSIRTab, 'numeric');
            app.IU_0.Value = 10;
            app.InitialInfectedDetectedEditFieldLabel = uilabel(app.ExtendedSIRTab);
            app.InitialInfectedDetectedEditFieldLabel.Text = 'Initial Infected (Detected)';
            app.ID_0 = uieditfield(app.ExtendedSIRTab, 'numeric');
            app.RunSimmulationButton = uibutton(app.ExtendedSIRTab, 'push');
            app.RunSimmulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.RunSimmulationButton.Text = 'Run Simmulation';
            app.MildNoSymptomsLabel = uilabel(app.ExtendedSIRTab);
            app.MildNoSymptomsLabel.Text = '(Mild/No Symptoms)';
            app.TimetoRecoverydaysLabel_2 = uilabel(app.ExtendedSIRTab);
            app.TimetoRecoverydaysLabel_2.Text = 'Time to Recovery (days)';
            app.t_r_nohosp = uislider(app.ExtendedSIRTab);
            app.t_r_nohosp.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.t_r_nohosp.Value = 70;
            app.SevereSymptomsNoHospitalLabel = uilabel(app.ExtendedSIRTab);
            app.SevereSymptomsNoHospitalLabel.Text = '(Severe Symptoms, No Hospital)';
            app.TimetoRecoverydaysLabel_3 = uilabel(app.ExtendedSIRTab);
            app.TimetoRecoverydaysLabel_3.Text = 'Time to Recovery (days)';
            app.t_r_hosp = uislider(app.ExtendedSIRTab);
            app.t_r_hosp.Limits = [0 50];
            app.t_r_hosp.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.t_r_hosp.Value = 14;
            app.WithAidofHospitalLabel = uilabel(app.ExtendedSIRTab);
            app.WithAidofHospitalLabel.Text = '(With Aid of Hospital)';
            app.ProbabilityoffatalityLabel = uilabel(app.ExtendedSIRTab);
            app.ProbabilityoffatalityLabel.Text = 'Probability of fatality';
            app.P_d = uislider(app.ExtendedSIRTab);
            app.P_d.Limits = [0 0.2];
            app.P_d.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.P_d.Value = 0.1;
            app.MeanNumberofSocialContactsLabel = uilabel(app.ExtendedSIRTab);
            app.MeanNumberofSocialContactsLabel.Text = '(Mean Number of Social Contacts)';
            app.MeanNumberofSocialContactsLabel_2 = uilabel(app.ExtendedSIRTab);
            app.MeanNumberofSocialContactsLabel_2.Text = '(Mean Number of Social Contacts)';
            app.SevereSymptomsnotreatmentLabel = uilabel(app.ExtendedSIRTab);
            app.SevereSymptomsnotreatmentLabel.Text = '(Severe Symptoms, no treatment)';
            app.per10kpeopleLabel = uilabel(app.ExtendedSIRTab);
            app.per10kpeopleLabel.Text = '(per 10k people)';
            app.TimetobecomeinfectiousdaysLabel = uilabel(app.ExtendedSIRTab);
            app.TimetobecomeinfectiousdaysLabel.Text = 'Time to become infectious (days)';
            app.t_i = uislider(app.ExtendedSIRTab);
            app.t_i.Limits = [0 10];
            app.t_i.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.t_i.Value = 2;
            app.TextArea = uitextarea(app.ExtendedSIRTab);
            app.TextArea.Value = {'The following allows for a simple infection model with reactions:'; ''; 'Susceptible  ->  Infected (rates tuned by increasing social distancing)'; 'Infected -> Infectous (rate tuned by time to become infectious)'; 'Infectous  -> Symptomatic (rate tuned by ''Time to Visible Symptoms'')'; 'Infectious -> Recovered (rate tuned by ''time to recovery, mild symptoms'')'; 'Symptomatic -> Hospitalized (rate tuned bynumber of open hospital beds)'; 'Symptomatic -> Recovered or Deceased (rates tuned by ''time to recovery, severe symptoms'' and ''Probability of Fatality'')'; 'Hospitalized -> Recovered or Deceased (rates tuned by ''time to recovery, with aid of hospital'' and ''Probability of Fatality'')'; ''; 'Default parameters are arbitrary. Suggestions for parameters and modification of events is very welcome.'; ''; ''};
            app.SimTypeButtonGroup = uibuttongroup(app.ExtendedSIRTab);
            app.SimTypeButtonGroup.Title = 'Sim Type';
            app.ode = uitogglebutton(app.SimTypeButtonGroup);
            app.ode.Text = 'ODE';
            app.ssa = uitogglebutton(app.SimTypeButtonGroup);
            app.ssa.Text = 'SSA';
            app.ssa.Value = true;
            app.SpatialSIRSimulationTab = uitab(app.TabGroup);
            app.SpatialSIRSimulationTab.Title = 'Spatial SIR Simulation';
            app.spat_sim = uiaxes(app.SpatialSIRSimulationTab);
            title(app.spat_sim, '')
            xlabel(app.spat_sim, '')
            ylabel(app.spat_sim, '')
            app.spat_sim.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.spat_sim.XTickLabel = '';
            app.spat_sim.YTickLabel = '';
            app.InfectionRatek_iEditFieldLabel = uilabel(app.SpatialSIRSimulationTab);
            app.InfectionRatek_iEditFieldLabel.Text = 'Infection Rate, k_i';
            app.ki = uieditfield(app.SpatialSIRSimulationTab, 'numeric');
            app.ki.Limits = [0 Inf];
            app.ki.Tooltip = {'This is the probability that a single infected individual will pass the virus to a nearby person in a given time step.'};
            app.ki.Value = 0.01;
            app.socialdistancevelocityv_sdEditFieldLabel = uilabel(app.SpatialSIRSimulationTab);
            app.socialdistancevelocityv_sdEditFieldLabel.Text = 'social distance velocity, v_sd';
            app.v_sd = uieditfield(app.SpatialSIRSimulationTab, 'numeric');
            app.v_sd.Limits = [0 Inf];
            app.v_sd.Tooltip = {'This is the rate of movement of people who practice social distancing.'};
            app.v_sd.Value = 0.001;
            app.cheatersvelocityv_cEditFieldLabel = uilabel(app.SpatialSIRSimulationTab);
            app.cheatersvelocityv_cEditFieldLabel.Text = 'cheater''s velocity, v_c';
            app.v_c = uieditfield(app.SpatialSIRSimulationTab, 'numeric');
            app.v_c.Limits = [0 Inf];
            app.v_c.Tooltip = {'This is the rate of movement of people who DO NOT practice social distancing.'};
            app.v_c.Value = 0.01;
            app.fractionofcheatersetaEditFieldLabel = uilabel(app.SpatialSIRSimulationTab);
            app.fractionofcheatersetaEditFieldLabel.Text = 'fraction of cheaters, eta';
            app.eta = uieditfield(app.SpatialSIRSimulationTab, 'numeric');
            app.eta.Limits = [0 1];
            app.eta.Tooltip = {'This is the fraction of people who DO NOT practice social distancing.'};
            app.eta.Value = 0.1;
            app.populationEditFieldLabel = uilabel(app.SpatialSIRSimulationTab);
            app.populationEditFieldLabel.Text = 'population';
            app.pop = uieditfield(app.SpatialSIRSimulationTab, 'numeric');
            app.pop.Limits = [10 Inf];
            app.pop.RoundFractionalValues = 'on';
            app.pop.Tooltip = {'This is the probability that a single infected individual will pass the virus to a nearby person in a given time step.'};
            app.pop.Value = 100;
            app.InitialnumberinfectedEditFieldLabel = uilabel(app.SpatialSIRSimulationTab);
            app.InitialnumberinfectedEditFieldLabel.Text = 'Initial number infected';
            app.inf = uieditfield(app.SpatialSIRSimulationTab, 'numeric');
            app.inf.Limits = [1 Inf];
            app.inf.RoundFractionalValues = 'on';
            app.inf.Tooltip = {'This is the number of people who are infected at initial time.'};
            app.inf.Value = 1;
            app.runsimulationButton = uibutton(app.SpatialSIRSimulationTab, 'push');
            app.runsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @runsimulationButtonPushed, true);
            app.runsimulationButton.Text = 'run simulation';
            app.spat_sim_2 = uiaxes(app.SpatialSIRSimulationTab);
            title(app.spat_sim_2, '')
            xlabel(app.spat_sim_2, 'Time')
            ylabel(app.spat_sim_2, '')
            app.spat_sim_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.spat_sim_2.XTickLabel = '';
            app.spat_sim_2.YTickLabel = '';
            % Show the figure after all components are created
            app.COVID19UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19_Matlab_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19UIFigure)
        end
    end
end