classdef COVID19_Matlab_App_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19UIFigure               matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        StatisticsTab                 matlab.ui.container.Tab
        place                         matlab.ui.container.ButtonGroup
        all                           matlab.ui.control.ToggleButton
        specific                      matlab.ui.control.ToggleButton
        TabGroup_2                    matlab.ui.container.TabGroup
        InfectionsTab                 matlab.ui.container.Tab
        ax_infections                 matlab.ui.control.UIAxes
        inf_type                      matlab.ui.container.ButtonGroup
        rec                           matlab.ui.control.ToggleButton
        cum                           matlab.ui.control.ToggleButton
        pt                            matlab.ui.control.ToggleButton
        rpt                           matlab.ui.control.ToggleButton
        inf_p_test                    matlab.ui.control.ToggleButton
        days_rec_inf                  matlab.ui.control.NumericEditField
        daywindowLabel                matlab.ui.control.Label
        perdayoverLabel               matlab.ui.control.Label
        InitlalDateForPlotDatePickerLabel  matlab.ui.control.Label
        InitlalDateForPlotDatePicker  matlab.ui.control.DatePicker
        DeathsTab                     matlab.ui.container.Tab
        ax_deaths                     matlab.ui.control.UIAxes
        GrowthRatebyRegionTab         matlab.ui.container.Tab
        UpdateButton                  matlab.ui.control.Button
        gr_table                      matlab.ui.control.Table
        gr_hist                       matlab.ui.control.UIAxes
        ByCountryorRegionButtonGroup  matlab.ui.container.ButtonGroup
        CountryButton                 matlab.ui.control.ToggleButton
        RegionButton                  matlab.ui.control.ToggleButton
        PlotTypesButtonGroup          matlab.ui.container.ButtonGroup
        lin                           matlab.ui.control.ToggleButton
        log                           matlab.ui.control.ToggleButton
        add_trend_lines               matlab.ui.control.CheckBox
        daystocomputetrendLabel       matlab.ui.control.Label
        CountriesLabel                matlab.ui.control.Label
        countries                     matlab.ui.control.ListBox
        UseLabel                      matlab.ui.control.Label
        days_for_trend                matlab.ui.control.NumericEditField
        mostrecentdaystovalidatepredictionsLabel  matlab.ui.control.Label
        HoldoutLabel                  matlab.ui.control.Label
        days_for_pred                 matlab.ui.control.NumericEditField
        StateProvinceLabel            matlab.ui.control.Label
        states                        matlab.ui.control.ListBox
        nmrl                          matlab.ui.container.ButtonGroup
        abs                           matlab.ui.control.ToggleButton
        rel                           matlab.ui.control.ToggleButton
        UpdatePlotsButton             matlab.ui.control.Button
        TrendTypeButtonGroup          matlab.ui.container.ButtonGroup
        LogLinearButton               matlab.ui.control.ToggleButton
        LogQuadraticButton            matlab.ui.control.ToggleButton
        MapsTab                       matlab.ui.container.Tab
        map                           matlab.ui.control.UIAxes
        ShowMapButton                 matlab.ui.control.Button
        TimeSliderLabel               matlab.ui.control.Label
        TimeSlider                    matlab.ui.control.Slider
        GenerateMovieButton           matlab.ui.control.Button
        RegionDropDownLabel           matlab.ui.control.Label
        RegionDropDown                matlab.ui.control.DropDown
        StatustoshowLabel             matlab.ui.control.Label
        map_what                      matlab.ui.control.DropDown
        nmrl_3                        matlab.ui.container.ButtonGroup
        abs_2                         matlab.ui.control.ToggleButton
        rel_2                         matlab.ui.control.ToggleButton
        forlastEditFieldLabel         matlab.ui.control.Label
        MovDays                       matlab.ui.control.NumericEditField
        daysLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        Countries
        States
        DATA % Description
        DATA_Deaths
        DATA_Recov
        DATA_pt
        Lat
        Long
        dates
        Pop_Data
        inf_vs_t
        dth_vs_t
        RealDates
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('../COVID-19/csse_covid_19_data/csse_covid_19_time_series/')
            
            try
                Y = importdata('time_series_covid19_confirmed_global.csv');
            catch
                [~, pathname, ~] = uigetfile('time_series_covid19_confirmed_global.csv','Navigate to find the File time_series_19-covid-Confirmed.csv');
                addpath(pathname);
                Y = importdata('time_series_covid19_confirmed_global.csv');
            end
            
            app.dates = Y.textdata(1,5:end);
            app.DATA = Y.data(:,3:end);
            app.Countries = Y.textdata(2:end,2);
            app.States = Y.textdata(2:end,1);
            app.Lat = Y.data(:,1);
            app.Long = Y.data(:,2);
            
            Y = importdata('time_series_covid19_deaths_global.csv');
            app.DATA_Deaths = Y.data(:,3:end);
            
            Y = importdata('time_series_covid19_recovered_global.csv');
            TMP = Y.data(:,3:end);
            st = Y.textdata(2:end,1);
            co = Y.textdata(2:end,2);
            
            app.DATA_Recov = zeros(size(app.DATA));
            for i=1:length(app.Countries)
                I = strcmp(co,app.Countries{i})&strcmp(st,app.States{i});
                if sum(I)==1
                    app.DATA_Recov(i,:) = TMP(I,:);
                end
            end

            app.countries.Items = unique(app.Countries);
            app.countries.Value = 'US';
            
            %%
            if ~exist('data/state_data.mat','file')
                extract_state_info_from_JHU_data;
            else
                FileInfo1 = dir('data/state_data.mat');
                FileInfo2 = dir('../COVID-19/csse_covid_19_data/csse_covid_19_time_series/time_series_covid19_confirmed_global.csv');
                if FileInfo2.datenum>FileInfo1.datenum
                    extract_state_info_from_JHU_data
                end
            end
            I = ~strcmp(app.Countries,'US');
            load('data/state_data.mat')
            app.DATA = [app.DATA(I,:);cell2mat(Z_DATA(:,5:end))];
            app.DATA_Deaths = [app.DATA_Deaths(I,:);cell2mat(Z_DATA_Deaths(:,5:end))];
            app.DATA_Recov = [app.DATA_Recov(I,:);cell2mat(Z_DATA_Recov(:,5:end))];
            app.DATA_pt(sum(I)+1:sum(I)+size(Z_DATA_Tested,1),:) = cell2mat(Z_DATA_Tested(:,5:end));
            app.Countries = [app.Countries(I);Z_DATA(:,2)];
            app.States = [app.States(I);Z_DATA(:,1)];
            app.Lat = [app.Lat(I);cell2mat(Z_DATA(:,3))];
            app.Long = [app.Long(I);cell2mat(Z_DATA(:,4))];
            app.RealDates = Z_DATA_DATES;
            
            population_scale(app);
            
            placeSelectionChanged(app)
            
%             app.countries_2.Items = unique(app.Countries);
%             app.countries_2.Value = 'US';
            
            %% SIR fit tab
%             app.sir_parameters.Data = {'k1',1};
%             app.sir_parameters.Data(1:6,1) = {'k_i';'k_r';'k_s';'k_d';'t_0';'Suc_0'};
%             app.sir_parameters.Data(1:6,2) = {2e-7;2e-3;0.5;1e-2;20;1e6};
            
            %% Maps Tab
            %             app.RegionDropDown.Items = {'World','US','US Per 10k','Europe'};
            app.RegionDropDown.Items = {'World','US','Europe'};
            app.RegionDropDown.Value = {'World'};
            Nt = length(app.dates);
            app.TimeSlider.MajorTicks = sort(unique([[Nt:-7:1]-Nt,7]));
            app.TimeSlider.MinorTicks = [-Nt+1:1:7];
            
            tlb = {app.dates{sort(Nt:-7:1)}};
            tlb{end} = [tlb{end},'(last)'];
            tlb{end+1} = 'future';
            
            app.TimeSlider.MajorTickLabels = tlb;
            app.TimeSlider.Value = 0;
                                
        end

        % Selection changed function: place
        function placeSelectionChanged(app, event)
            if app.specific.Value
                app.countries.Items = unique(app.Countries);
                app.countries.Value = 'US';
                countriesValueChanged(app, event)
            end
            Make_Plot(app)
        end

        % Callback function: InitlalDateForPlotDatePicker, 
        % PlotTypesButtonGroup, UpdatePlotsButton, days_for_pred, 
        % days_for_trend, days_rec_inf, inf_type, nmrl, states
        function PlotTypeButtonGroupSelectionChanged(app, event)
            Make_Plot(app)
        end

        % Value changed function: countries
        function countriesValueChanged(app, event)
            app.specific.Value=1;
            if length(app.countries.Value)==1
                [J] = ismember(app.Countries,app.countries.Value);
                app.states.Items = ['ALL',app.States(J)']';
            else
                app.states.Items = {'ALL'};
            end
            Make_Plot(app)
        end

        % Callback function: ByCountryorRegionButtonGroup, 
        % UpdateButton
        function UpdateButtonPushed(app, event)
            Make_Growth_Rate_Table(app)
        end

        % Callback function: RegionDropDown, ShowMapButton, 
        % TimeSlider, map_what, nmrl_3
        function ShowMapButtonPushed(app, event)
            Make_Map(app);
        end

        % Button pushed function: GenerateMovieButton
        function GenerateMovieButtonPushed(app, event)
            Make_Movie(app)
        end

        % Callback function
        function RunSimmulationButtonPushed(app, event)
            Run_SSA_Sim(app)
        end

        % Callback function
        function update_sir_plotPushed(app, event)
            Plot_SIR_Fit(app)
        end

        % Callback function
        function SIRModelFitTabSizeChanged(app, event)
      
        end

        % Callback function
        function FittoDataButtonPushed(app, event)
            Fit_to_data(app)
        end

        % Callback function
        function PeopleDropDownValueChanged(app, event)
            app.rel_date.Value=1;
            Make_Plot(app);
        end

        % Callback function
        function runsimulationButtonPushed(app, event)
            spatial_sir_model(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19UIFigure = uifigure('Visible', 'off');
            app.COVID19UIFigure.Name = 'COVID-19';
            app.TabGroup = uitabgroup(app.COVID19UIFigure);
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.place = uibuttongroup(app.StatisticsTab);
            app.place.SelectionChangedFcn = createCallbackFcn(app, @placeSelectionChanged, true);
            app.place.Title = 'All Countries or Selected Subset of Countries';
            app.all = uitogglebutton(app.place);
            app.all.Text = 'All Countries';
            app.all.Value = true;
            app.specific = uitogglebutton(app.place);
            app.specific.Text = 'Specific Countires';
            app.TabGroup_2 = uitabgroup(app.StatisticsTab);
            app.InfectionsTab = uitab(app.TabGroup_2);
            app.InfectionsTab.Title = 'Infections';
            app.ax_infections = uiaxes(app.InfectionsTab);
            title(app.ax_infections, 'Infections over time')
            xlabel(app.ax_infections, 'Time (days)')
            ylabel(app.ax_infections, 'Infections')
            app.inf_type = uibuttongroup(app.InfectionsTab);
            app.inf_type.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.rec = uitogglebutton(app.inf_type);
            app.rec.Text = 'Recent Infections';
            app.cum = uitogglebutton(app.inf_type);
            app.cum.Text = 'Cumulative Infections';
            app.cum.Value = true;
            app.pt = uitogglebutton(app.inf_type);
            app.pt.Text = 'Cumulative Tested';
            app.rpt = uitogglebutton(app.inf_type);
            app.rpt.Text = 'Recent Tested';
            app.inf_p_test = uitogglebutton(app.inf_type);
            app.inf_p_test.Text = 'Fraction Positive';
            app.days_rec_inf = uieditfield(app.InfectionsTab, 'numeric');
            app.days_rec_inf.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_rec_inf.Value = 5;
            app.daywindowLabel = uilabel(app.InfectionsTab);
            app.daywindowLabel.Text = 'day window';
            app.perdayoverLabel = uilabel(app.InfectionsTab);
            app.perdayoverLabel.Text = 'per day over ';
            app.InitlalDateForPlotDatePickerLabel = uilabel(app.InfectionsTab);
            app.InitlalDateForPlotDatePickerLabel.Text = 'Initlal Date For Plot:';
            app.InitlalDateForPlotDatePicker = uidatepicker(app.InfectionsTab);
            app.InitlalDateForPlotDatePicker.Limits = [datetime([2020 1 20]) datetime([2021 12 31])];
            app.InitlalDateForPlotDatePicker.DisplayFormat = 'MM/dd/uuuu';
            app.InitlalDateForPlotDatePicker.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.InitlalDateForPlotDatePicker.Value = datetime([2021 8 31]);
            app.DeathsTab = uitab(app.TabGroup_2);
            app.DeathsTab.Title = 'Deaths';
            app.ax_deaths = uiaxes(app.DeathsTab);
            title(app.ax_deaths, 'Deaths over time')
            xlabel(app.ax_deaths, 'Time (days)')
            ylabel(app.ax_deaths, 'Deaths')
            app.GrowthRatebyRegionTab = uitab(app.TabGroup_2);
            app.GrowthRatebyRegionTab.Title = 'Growth Rate by Region';
            app.UpdateButton = uibutton(app.GrowthRatebyRegionTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.gr_table = uitable(app.GrowthRatebyRegionTab);
            app.gr_table.ColumnName = {'Country/Region'; 'Doubling Time (days)'};
            app.gr_table.RowName = {};
            app.gr_hist = uiaxes(app.GrowthRatebyRegionTab);
            title(app.gr_hist, 'Histogram')
            xlabel(app.gr_hist, 'Doubling Time (days)')
            ylabel(app.gr_hist, 'Probability Density')
            app.ByCountryorRegionButtonGroup = uibuttongroup(app.GrowthRatebyRegionTab);
            app.ByCountryorRegionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.ByCountryorRegionButtonGroup.Title = 'By Country or Region:';
            app.CountryButton = uitogglebutton(app.ByCountryorRegionButtonGroup);
            app.CountryButton.Text = 'Country';
            app.CountryButton.Value = true;
            app.RegionButton = uitogglebutton(app.ByCountryorRegionButtonGroup);
            app.RegionButton.Text = 'Region';
            app.PlotTypesButtonGroup = uibuttongroup(app.StatisticsTab);
            app.PlotTypesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypesButtonGroup.Title = 'Plot Types:';
            app.lin = uitogglebutton(app.PlotTypesButtonGroup);
            app.lin.Text = 'Linear';
            app.log = uitogglebutton(app.PlotTypesButtonGroup);
            app.log.Text = 'Logarithmic';
            app.log.Value = true;
            app.add_trend_lines = uicheckbox(app.StatisticsTab);
            app.add_trend_lines.Text = 'Include Trend lines';
            app.daystocomputetrendLabel = uilabel(app.StatisticsTab);
            app.daystocomputetrendLabel.Text = 'days to compute trend';
            app.CountriesLabel = uilabel(app.StatisticsTab);
            app.CountriesLabel.Text = 'Countries';
            app.countries = uilistbox(app.StatisticsTab);
            app.countries.Multiselect = 'on';
            app.countries.ValueChangedFcn = createCallbackFcn(app, @countriesValueChanged, true);
            app.countries.Value = {'Item 1'};
            app.UseLabel = uilabel(app.StatisticsTab);
            app.UseLabel.Text = 'Use';
            app.days_for_trend = uieditfield(app.StatisticsTab, 'numeric');
            app.days_for_trend.Limits = [3 20];
            app.days_for_trend.RoundFractionalValues = 'on';
            app.days_for_trend.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_for_trend.Value = 5;
            app.mostrecentdaystovalidatepredictionsLabel = uilabel(app.StatisticsTab);
            app.mostrecentdaystovalidatepredictionsLabel.Text = 'most recent days to validate predictions';
            app.HoldoutLabel = uilabel(app.StatisticsTab);
            app.HoldoutLabel.Text = 'Hold out';
            app.days_for_pred = uieditfield(app.StatisticsTab, 'numeric');
            app.days_for_pred.Limits = [0 10];
            app.days_for_pred.RoundFractionalValues = 'on';
            app.days_for_pred.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_for_pred.Value = 1;
            app.StateProvinceLabel = uilabel(app.StatisticsTab);
            app.StateProvinceLabel.Text = 'State/Province';
            app.states = uilistbox(app.StatisticsTab);
            app.states.Items = {'ALL'};
            app.states.Multiselect = 'on';
            app.states.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.states.Value = {'ALL'};
            app.nmrl = uibuttongroup(app.StatisticsTab);
            app.nmrl.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.nmrl.Title = 'Normalization (only for countries with known populations)';
            app.abs = uitogglebutton(app.nmrl);
            app.abs.Text = 'Absolute Numbers';
            app.abs.Value = true;
            app.rel = uitogglebutton(app.nmrl);
            app.rel.Text = 'Per 10k Persons';
            app.UpdatePlotsButton = uibutton(app.StatisticsTab, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.UpdatePlotsButton.Text = 'Update Plots';
            app.TrendTypeButtonGroup = uibuttongroup(app.StatisticsTab);
            app.TrendTypeButtonGroup.Title = 'Trend Type:';
            app.LogLinearButton = uitogglebutton(app.TrendTypeButtonGroup);
            app.LogLinearButton.Text = 'Log-Linear';
            app.LogLinearButton.Value = true;
            app.LogQuadraticButton = uitogglebutton(app.TrendTypeButtonGroup);
            app.LogQuadraticButton.Text = 'Log-Quadratic';
            app.MapsTab = uitab(app.TabGroup);
            app.MapsTab.Title = 'Maps';
            app.map = uiaxes(app.MapsTab);
            title(app.map, 'Map of Pandemic')
            xlabel(app.map, '')
            ylabel(app.map, '')
            app.map.XTick = [];
            app.map.YTick = [];
            app.ShowMapButton = uibutton(app.MapsTab, 'push');
            app.ShowMapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.ShowMapButton.Text = 'Show Map';
            app.TimeSliderLabel = uilabel(app.MapsTab);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.MapsTab);
            app.TimeSlider.Limits = [-42 7];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.GenerateMovieButton = uibutton(app.MapsTab, 'push');
            app.GenerateMovieButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMovieButtonPushed, true);
            app.GenerateMovieButton.Text = 'Generate Movie';
            app.RegionDropDownLabel = uilabel(app.MapsTab);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.MapsTab);
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.StatustoshowLabel = uilabel(app.MapsTab);
            app.StatustoshowLabel.Text = 'Status to show';
            app.map_what = uidropdown(app.MapsTab);
            app.map_what.Items = {'Deaths', 'Recent Infected', 'Cumulative Infected'};
            app.map_what.ValueChangedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.map_what.Value = 'Deaths';
            app.nmrl_3 = uibuttongroup(app.MapsTab);
            app.nmrl_3.SelectionChangedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.nmrl_3.Title = 'Normalization';
            app.abs_2 = uitogglebutton(app.nmrl_3);
            app.abs_2.Text = 'Absolute Numbers';
            app.abs_2.Value = true;
            app.rel_2 = uitogglebutton(app.nmrl_3);
            app.rel_2.Text = 'Per 10k Persons';
            app.forlastEditFieldLabel = uilabel(app.MapsTab);
            app.forlastEditFieldLabel.Text = 'for last';
            app.MovDays = uieditfield(app.MapsTab, 'numeric');
            app.MovDays.Limits = [10 500];
            app.MovDays.RoundFractionalValues = 'on';
            app.MovDays.Value = 60;
            app.daysLabel = uilabel(app.MapsTab);
            app.daysLabel.Text = 'days';
            % Show the figure after all components are created
            app.COVID19UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19_Matlab_App_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19UIFigure)
        end
    end
end